classdef Audio_Signals_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectedFileOperationPanel  matlab.ui.container.Panel
        GridLayout2_2               matlab.ui.container.GridLayout
        SaveasButton                matlab.ui.control.Button
        ThismightreduceOriginalfileaudioqualityLabel  matlab.ui.control.Label
        AmplitudeSpinner            matlab.ui.control.Spinner
        AmplitudeSpinnerLabel       matlab.ui.control.Label
        ThismightChangeOriginalFileSampleRateLabel  matlab.ui.control.Label
        SpeedSpinner                matlab.ui.control.Spinner
        SpeedSpinnerLabel           matlab.ui.control.Label
        NOTEThiswilloverwriteselectedfileLabel  matlab.ui.control.Label
        InverseCheckBox             matlab.ui.control.CheckBox
        ReverseCheckBox             matlab.ui.control.CheckBox
        SaveButton                  matlab.ui.control.Button
        AudioFileSelectionPanel     matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        MergeselectedfilesButton    matlab.ui.control.Button
        DeleteButton                matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        ListBox                     matlab.ui.control.ListBox
        SoundSettingPanel           matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        Slider                      matlab.ui.control.Slider
        StopButton_2                matlab.ui.control.Button
        PauseResumeButton           matlab.ui.control.StateButton
        PlayButton                  matlab.ui.control.Button
        al                          matlab.ui.control.Label
        tfile                       matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            app.ListBox.Items(index) = [];
            app.ListBox.ItemsData(index) = [];
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            if (isempty(app.ListBox.Items) ~= true)
                ListBoxValueChanged(app, event);
            end 
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            clear y;
            [filename,filepath] = uigetfile('*.wav');  %open a mat file
            filepath = strcat(filepath,filename);
            if (isempty(filepath) ~= true)
                app.tfile.Text = filepath;
                [y, fs] = audioread(filepath);
                t = (0:length(y)) / fs;
                t(end) = [];
                app.al.Text = strcat(num2str(t(end))," Second");
                plot(app.UIAxes,t, y);
                h=zoom(app.UIAxes);
                h.Motion = 'horizontal';
                h.Enable = 'on';
            
                itemList = app.ListBox.Items;
                itemData = app.ListBox.ItemsData;
                itemList{end+1} = filename;
                itemData{end+1} = filepath;
                app.ListBox.Items = itemList;
                app.ListBox.ItemsData = itemData;
            end
            
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            value = app.ListBox.ItemsData{index};
            app.tfile.Text = value;
            [y, fs] = audioread(value);
            t = (0:length(y)) / fs;
            t(end) = [];
            plot(app.UIAxes,t, y);
            
            k = app.Slider.Value;
            global player
            player = audioplayer(y*k, fs);
            filePath = app.ListBox.Value;
            if (length(filePath) == 1)
                set(app.SelectedFileOperationPanel,'Enable','on')
                set(app.ReverseCheckBox,'Value',false)
                set(app.InverseCheckBox,'Value',false)
                set(app.SpeedSpinner,'Value',1)
                set(app.AmplitudeSpinner,'Value',1)
            else
                set(app.SelectedFileOperationPanel,'Enable','off')
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global player
            play(player);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            global player
            stop(player);
        end

        % Value changed function: PauseResumeButton
        function PauseResumeButtonValueChanged(app, event)
            value = app.PauseResumeButton.Value;
            global player
            if (value == 1) 
                pause(player);
            else
                resume(player);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global player
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            app.tfile.Text = string(isplaying(player));
            if (index ~= 0.0 & isplaying(player) ~= false)
                value = app.ListBox.ItemsData{index};
                pause(player);
                NewStart = get(player, 'CurrentSample')+1;
                stop(player);
                k = app.Slider.Value;
                [y, fs] = audioread(value);
                player = audioplayer(y*k, fs);
                play(player, NewStart);
            end
        end

        % Button pushed function: MergeselectedfilesButton
        function MergeselectedfilesButtonPushed(app, event)
            filePath = app.ListBox.Value;
            global player
            if (length(filePath) <= 0)
                return
            end
            if (length(filePath) == 1)
                [y, fs] = audioread(filePath{1});
                t = (0:length(y)) / fs;
                t(end) = [];
                plot(app.UIAxes2,t, y); 
                %plot(app.UIAxes3,t,y);
                x = y;
                df = fs/length(x);
                f = -fs/2:df:fs/2-df;
                fftA = fftshift(fft(x))/length(fft(x));
                
                plot(app.UIAxes3, f, abs(fftA));
                %title ('FFT Spectrum');
                k = app.Slider.Value;
                player = audioplayer(y*k, fs);
               return
            end
            
            for i = 2:length(filePath)
                if (i == 2)
                    [y1, fs1] = audioread(filePath{1});
                    
                    [y2, fs2] = audioread(filePath{2});
                    
                    lenY = size(y1, 1);
                    lenZ = size(y2, 1);
                    len = max(lenY, lenZ);
                    S = zeros(len, size(y1, 2));
                    S(1:lenY, :) = y1;
                    S(1:lenZ, :) = S(1:lenZ, :) + y2;
                    maxValue = max(abs(S(:)));
                    S = S / maxValue;
                    if (length(y1) >= length(y2))
                        t = (1:length(y1)) / fs1;
                        fs = fs1;
                    else
                        t = (1:length(y2)) / fs2;
                        fs = fs2;
                    end
                else
                    [y, fs_] = audioread(filePath{i});
                    y_dum = S;
                    fs_dum = fs;
                    
                    lenY = size(y_dum, 1);
                    lenZ = size(y, 1);
                    len = max(lenY, lenZ);
                    S = zeros(len, size(y_dum, 2));
                    S(1:lenY, :) = y_dum;
                    S(1:lenZ, :) = S(1:lenZ, :) + y;
                    maxValue = max(abs(S(:)));
                    S = S / maxValue;
                    if (length(y_dum) >= length(y))
                        t = (1:length(y_dum)) / fs;
                        fs = fs_dum;
                    else
                        t = (1:length(y)) / fs_;
                        fs = fs_;
                    end
                end
            end
            plot(app.UIAxes2,t, S);
            x = S;
            Fs = fs;
            df = Fs/length(S);
            f = -Fs/2:df:Fs/2-df;
            fftA = fftshift(fft(x))/length(fft(x));
            
            plot(app.UIAxes3, f, abs(fftA));
            k = app.Slider.Value;
            player = audioplayer(S*k, Fs);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filePath = app.ListBox.Value;
            if (length(filePath) == 1)
                set(app.SelectedFileOperationPanel,'Enable','off')
                if (app.ReverseCheckBox.Value)
                    [y, fs] = audioread(filePath{1});
                    y2 = flipud(y);
                    audiowrite(filePath{1},y2,fs);
                end
                if (app.InverseCheckBox.Value)
                    [y, fs] = audioread(filePath{1});
                    y2 = y * -1;
                    audiowrite(filePath{1},y2,fs);
                end
                [y, fs] = audioread(filePath{1});
                newSampleRate = app.SpeedSpinner.Value * fs;
                if (newSampleRate >= 80 && newSampleRate <= 1000000) 
                    y2 = y * app.AmplitudeSpinner.Value;
                    audiowrite(filePath{1},y2,fix(app.SpeedSpinner.Value * fs));
                end
                
                
                set(app.SelectedFileOperationPanel,'Enable','on')
                set(app.InverseCheckBox,'Value',false)
                set(app.ReverseCheckBox,'Value',false)
                set(app.SpeedSpinner,'Value',1)
                set(app.AmplitudeSpinner,'Value',1)
            end           
            
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            filter = {'*.wav';'*.*'};
            [file, path] = uiputfile(filter);
            filetarget = strcat(path,file);
            
            filePath = app.ListBox.Value;
            if (length(filePath) == 1)
                set(app.SelectedFileOperationPanel,'Enable','off')
                if (app.ReverseCheckBox.Value)
                    [y, fs] = audioread(filePath{1});
                    y2 = flipud(y);
                    audiowrite(filetarget,y2,fs);
                end
                if (app.InverseCheckBox.Value)
                    [y, fs] = audioread(filePath{1});
                    y2 = y * -1;
                    audiowrite(filetarget,y2,fs);
                end
                [y, fs] = audioread(filePath{1});
                newSampleRate = app.SpeedSpinner.Value * fs;
                if (newSampleRate >= 80 && newSampleRate <= 1000000) 
                    y2 = y * app.AmplitudeSpinner.Value;
                    audiowrite(filetarget,y2,app.SpeedSpinner.Value * fs);
                end                
                set(app.SelectedFileOperationPanel,'Enable','on')
                set(app.InverseCheckBox,'Value',false)
                set(app.ReverseCheckBox,'Value',false)
                set(app.SpeedSpinner,'Value',1)
                set(app.AmplitudeSpinner,'Value',1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time')
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Merge')
            xlabel(app.UIAxes2, 'Seconds')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Frequency Spectrum')
            xlabel(app.UIAxes3, 'Frequency (Hz)')
            ylabel(app.UIAxes3, 'Magnitude ')
            zlabel(app.UIAxes3, 'Z')
            app.tfile = uilabel(app.UIFigure);
            app.tfile.Text = 'NO SELECTED FILE';
            app.al = uilabel(app.UIFigure);
            app.al.Text = {''; ''};
            app.SoundSettingPanel = uipanel(app.UIFigure);
            app.SoundSettingPanel.Title = 'Sound Setting';
            app.GridLayout = uigridlayout(app.SoundSettingPanel);
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseResumeButton = uibutton(app.GridLayout, 'state');
            app.PauseResumeButton.ValueChangedFcn = createCallbackFcn(app, @PauseResumeButtonValueChanged, true);
            app.PauseResumeButton.Text = 'Pause / Resume';
            app.StopButton_2 = uibutton(app.GridLayout, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = {'Stop'; ''};
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.5 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.5;
            app.AudioFileSelectionPanel = uipanel(app.UIFigure);
            app.AudioFileSelectionPanel.Title = 'Audio File Selection';
            app.GridLayout2 = uigridlayout(app.AudioFileSelectionPanel);
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.GridLayout2, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.MergeselectedfilesButton = uibutton(app.GridLayout2, 'push');
            app.MergeselectedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @MergeselectedfilesButtonPushed, true);
            app.MergeselectedfilesButton.Text = 'Merge selected files';
            app.SelectedFileOperationPanel = uipanel(app.UIFigure);
            app.SelectedFileOperationPanel.Enable = 'off';
            app.SelectedFileOperationPanel.Title = 'Selected File Operation';
            app.GridLayout2_2 = uigridlayout(app.SelectedFileOperationPanel);
            app.SaveButton = uibutton(app.GridLayout2_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ReverseCheckBox = uicheckbox(app.GridLayout2_2);
            app.ReverseCheckBox.Text = 'Reverse';
            app.InverseCheckBox = uicheckbox(app.GridLayout2_2);
            app.InverseCheckBox.Text = 'Inverse';
            app.NOTEThiswilloverwriteselectedfileLabel = uilabel(app.GridLayout2_2);
            app.NOTEThiswilloverwriteselectedfileLabel.Text = 'NOTE: This will over write selected file';
            app.SpeedSpinnerLabel = uilabel(app.GridLayout2_2);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.GridLayout2_2);
            app.SpeedSpinner.Step = 0.05;
            app.SpeedSpinner.Limits = [0.01 6];
            app.SpeedSpinner.Value = 1;
            app.ThismightChangeOriginalFileSampleRateLabel = uilabel(app.GridLayout2_2);
            app.ThismightChangeOriginalFileSampleRateLabel.Text = 'This might Change Original File Sample Rate';
            app.AmplitudeSpinnerLabel = uilabel(app.GridLayout2_2);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.GridLayout2_2);
            app.AmplitudeSpinner.Step = 0.05;
            app.AmplitudeSpinner.Limits = [0 10];
            app.AmplitudeSpinner.Value = 1;
            app.ThismightreduceOriginalfileaudioqualityLabel = uilabel(app.GridLayout2_2);
            app.ThismightreduceOriginalfileaudioqualityLabel.Text = 'This might reduce Original file audio quality';
            app.SaveasButton = uibutton(app.GridLayout2_2, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_Signals_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end